classdef doublediffraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        LambdaSliderLabel  matlab.ui.control.Label
        LambdaSlider       matlab.ui.control.Slider
        dSliderLabel       matlab.ui.control.Label
        dSlider            matlab.ui.control.Slider
        aSliderLabel       matlab.ui.control.Label
        aSlider            matlab.ui.control.Slider
        LSliderLabel       matlab.ui.control.Label
        LSlider            matlab.ui.control.Slider
    end

    %%双缝衍射
    properties (Access = public)
        Property % Description
        Lambda=500e-9
        d=0.15e-3
        a=30*1e-6
        L=1
        H=0.1
        ScreenX=1048
        ScreenY=350
        x,theta,beta,alpha,x1,x2,I,II
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.x=linspace(-app.H/2,app.H/2,app.ScreenX)
            app.theta=atan(app.x/app.L)
            app.beta=pi*app.d*sin(app.theta)/app.Lambda
            app.alpha=pi*app.a*sin(app.theta)/app.Lambda
            app.x1=cos(app.beta).^2
            app.x2=(sin(app.alpha)./app.alpha).^2
            app.I=app.x1.*app.x2
            app.II=repmat(app.I,[app.ScreenY 1])

            imshow(nthroot(app.II,5),'Parent',app.UIAxes)
            plot(app.UIAxes2,app.x,app.I,app.x,app.x2,'--')
            
            
        end

        % Value changing function: LambdaSlider
        function LambdaSliderValueChanging(app, event)
            app.Lambda = event.Value*1e-9;
            app.beta=pi*app.d*sin(app.theta)/app.Lambda
            app.alpha=pi*app.a*sin(app.theta)/app.Lambda
            app.x1=cos(app.beta).^2
            app.x2=(sin(app.alpha)./app.alpha).^2
            app.I=app.x1.*app.x2
            app.II=repmat(app.I,[app.ScreenY 1])

            imshow(nthroot(app.II,5),'Parent',app.UIAxes)
            plot(app.UIAxes2,app.x,app.I,app.x,app.x2,'--')
        end

        % Value changing function: dSlider
        function dSliderValueChanging(app, event)
            app.d = event.Value*1e-3;
           
            app.beta=pi*app.d*sin(app.theta)/app.Lambda
            app.alpha=pi*app.a*sin(app.theta)/app.Lambda
            app.x1=cos(app.beta).^2
            app.x2=(sin(app.alpha)./app.alpha).^2
            app.I=app.x1.*app.x2
            app.II=repmat(app.I,[app.ScreenY 1])

            imshow(nthroot(app.II,5),'Parent',app.UIAxes)
            plot(app.UIAxes2,app.x,app.I,app.x,app.x2,'--')
        end

        % Value changing function: aSlider
        function aSliderValueChanging(app, event)
            app.a = event.Value;
            app.alpha=pi*app.a*sin(app.theta)/app.Lambda
            app.x1=cos(app.beta).^2
            app.x2=(sin(app.alpha)./app.alpha).^2
            app.I=app.x1.*app.x2
            app.II=repmat(app.I,[app.ScreenY 1])
            imshow(nthroot(app.II,5),'Parent',app.UIAxes)
            plot(app.UIAxes2,app.x,app.I,app.x,app.x2,'--')
        end

        % Value changing function: LSlider
        function LSliderValueChanging(app, event)
            app.L = event.Value;
            app.theta=atan(app.x/app.L)
            app.beta=pi*app.d*sin(app.theta)/app.Lambda
            app.alpha=pi*app.a*sin(app.theta)/app.Lambda
            app.x1=cos(app.beta).^2
            app.x2=(sin(app.alpha)./app.alpha).^2
            app.I=app.x1.*app.x2
            app.II=repmat(app.I,[app.ScreenY 1])

            imshow(nthroot(app.II,5),'Parent',app.UIAxes)
            plot(app.UIAxes2,app.x,app.I,app.x,app.x2,'--')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'x/m')
            ylabel(app.UIAxes2, '相对光强')
            app.LambdaSliderLabel = uilabel(app.UIFigure);
            app.LambdaSliderLabel.Text = 'Lambda';
            app.LambdaSlider = uislider(app.UIFigure);
            app.LambdaSlider.Limits = [400 760];
            app.LambdaSlider.ValueChangingFcn = createCallbackFcn(app, @LambdaSliderValueChanging, true);
            app.LambdaSlider.Value = 500;
            app.dSliderLabel = uilabel(app.UIFigure);
            app.dSliderLabel.Text = 'd';
            app.dSlider = uislider(app.UIFigure);
            app.dSlider.Limits = [0.01 1];
            app.dSlider.ValueChangingFcn = createCallbackFcn(app, @dSliderValueChanging, true);
            app.dSlider.Value = 0.15;
            app.aSliderLabel = uilabel(app.UIFigure);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.UIFigure);
            app.aSlider.Limits = [1e-06 0.0001];
            app.aSlider.ValueChangingFcn = createCallbackFcn(app, @aSliderValueChanging, true);
            app.aSlider.Value = 3e-05;
            app.LSliderLabel = uilabel(app.UIFigure);
            app.LSliderLabel.Text = 'L';
            app.LSlider = uislider(app.UIFigure);
            app.LSlider.Limits = [0.1 5];
            app.LSlider.ValueChangingFcn = createCallbackFcn(app, @LSliderValueChanging, true);
            app.LSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = doublediffraction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end